classdef FCS_Project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FCSErrorCheckingMechanismUIFigure  matlab.ui.Figure
        GeneratorPanel            matlab.ui.container.Panel
        TransmittedsequenceLabel  matlab.ui.control.Label
        FCSbits                   matlab.ui.control.Label
        GenFCS                    matlab.ui.control.Label
        MessageLabel              matlab.ui.control.Label
        Msg                       matlab.ui.control.EditField
        GeneratorsequenceLabel    matlab.ui.control.Label
        Gen                       matlab.ui.control.EditField
        TxMsg                     matlab.ui.control.Label
        GenerateButton            matlab.ui.control.Button
        ReceiverPanel             matlab.ui.container.Panel
        ErrorlocationsLabel       matlab.ui.control.Label
        FCSbits_2                 matlab.ui.control.Label
        RxError                   matlab.ui.control.Label
        RxErrLoc                  matlab.ui.control.Label
        FCSbits_3                 matlab.ui.control.Label
        RxRem                     matlab.ui.control.Label
        FCSbits_4                 matlab.ui.control.Label
        FCSbits_5                 matlab.ui.control.Label
        RxStatus                  matlab.ui.control.Label
        RxDataword                matlab.ui.control.Label
        ReceivedsequenceLabel     matlab.ui.control.Label
        RxMsg                     matlab.ui.control.EditField
        RecButton                 matlab.ui.control.Button
        SeqLenError_2             matlab.ui.control.Label
        AlterPanel                matlab.ui.container.Panel
        FCSbits_6                 matlab.ui.control.Label
        AltError                  matlab.ui.control.Label
        FCSbits_7                 matlab.ui.control.Label
        AltRem                    matlab.ui.control.Label
        FCSbits_8                 matlab.ui.control.Label
        FCSbits_9                 matlab.ui.control.Label
        AltStatus                 matlab.ui.control.Label
        AltDataword               matlab.ui.control.Label
        SeqLenError               matlab.ui.control.Label
        FCSbits_11                matlab.ui.control.Label
        UpdatedSeq                matlab.ui.control.Label
        ErrorsequenceLabel        matlab.ui.control.Label
        ErrorSeq                  matlab.ui.control.EditField
        AlterButton               matlab.ui.control.Button
        ResetButton               matlab.ui.control.Button
        ExitButton                matlab.ui.control.Button
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            close all
            movegui(app.FCSErrorCheckingMechanismUIFigure,"center");
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            msg = num2str(app.Msg.Value) - '0';
            % Get the length of original message (K) bits
            len = length(msg);

            gen = num2str(app.Gen.Value) - '0';
            % Get the length of generator sequence (N+1) bits
            len2 = length(gen);
        
            if (~isempty(app.Gen.Value) && ~isempty(app.Msg.Value))
                % Pad the original message with N-bit amount of 0's in the end 
                new_msg = [msg, zeros(1,len2-1)];

                %-------------------------------------------------------------
                % Long division process: At the end of calculation, only the 
                % remainder (N bits) is recorded 
                %-------------------------------------------------------------
                bit_cnt = len2;      % next-bit count tracker
                for i = 1:len
                    bit_cnt = bit_cnt + 1;
            
                    % In the very first calculation, 'currVal' is the leftmost (N+1)-bit 
                    % MSB of the dividend, otherwise, it is based on the current
                    % 'nextVal' value
                    if (i == 1)
                        currVal = new_msg(1:len2);
                    else
                        currVal = nextVal;
                    end
                    
                    % The value to be XOR'ed with 'currVal' is based on MSB of 'currVal' 
                    % MSB = 1: XOR with divisor, MSB = 0: XOR with 0's
                    if (currVal(1) == 1)
                        temp = bitxor(currVal, gen);   
                    else
                        temp = bitxor(currVal, zeros(1, len2));
                    end
                    
                    % Lastly, if the calculation is not yet the last one, take the
                    % N 'temp' LSB bits and concatenate it with the next bit from the
                    % original message, otherwise, the resultant is the remainder
                    if (i < len)
                        nextVal = [temp(2:end), new_msg(bit_cnt)];
                    else
                        remainder = temp(2:end);
                    end
                end
                remBits = strrep(num2str(remainder), ' ', '');
                app.GenFCS.Text = remBits;
                app.TxMsg.Text = strcat(app.Msg.Value,remBits);
            
                % Enable received and alter panels and disable generator until
                % reset
                app.RxMsg.Editable = true;
                app.RecButton.Enable = true;
                app.ErrorSeq.Editable = true;
                app.AlterButton.Enable = true;
                app.Msg.Editable = false;
                app.Gen.Editable = false;
                app.GenerateButton.Enable = false;                       
                app.ResetButton.Enable = true;
            end

        end

        % Button pushed function: RecButton
        function RecButtonPushed(app, event)
            % Converts the string input to a logical vector
            tx_msg = logical(num2str(app.TxMsg.Text) - '0');
            
            gen = num2str(app.Gen.Value) - '0';
            % Get the generator sequence length: (N+1) bits
            len = length(gen);
        
            rx_msg = num2str(app.RxMsg.Value) - '0';
            % Get the original message length: K bits
            len2 = length(rx_msg) - len+1;
        
        
            if (~isempty(app.RxMsg.Value) && length(rx_msg) > len)
                %-------------------------------------------------------------
                % Long division process: At the end of calculation, only the 
                % remainder (N bits) is recorded 
                %-------------------------------------------------------------
                bit_cnt = len;      % next-bit count tracker
                for i = 1:len2
                    bit_cnt = bit_cnt + 1;
            
                    % In the very first calculation, 'currVal' is the leftmost (N+1)-bit 
                    % MSB of the dividend, otherwise, it is based on the current
                    % 'nextVal' value
                    if (i == 1)
                        currVal = rx_msg(1:len);
                    else
                        currVal = nextVal;
                    end
                    
                    % The value to be XOR'ed with 'currVal' is based on MSB of 'currVal' 
                    % MSB = 1: XOR with divisor, MSB = 0: XOR with 0's
                    if (currVal(1) == 1)
                        temp = xor(currVal, gen);   
                    else
                        temp = xor(currVal, zeros(1, len));
                    end
                    
                    % Lastly, if the calculation is not yet the last one, take the
                    % N 'temp' LSB bits and concatenate it with the next bit from the
                    % original message, otherwise, the resultant is the remainder
                    if (i < len2)
                        nextVal = [temp(2:end), rx_msg(bit_cnt)];
                    else
                        remainder = temp(2:end);
                    end
                end
                
                rxRemBits = strrep(num2str(remainder), ' ', '');
                app.RxRem.Text = rxRemBits;

                if(isequal(length(rx_msg), length(tx_msg)))
                    app.SeqLenError_2.Text = '';
                    err = xor(tx_msg, rx_msg);
                    % If remainder is zero: accept the frame and show
                    % errors (if present)
                    if (isequal(remainder, zeros(1, len-1)))
                        app.RxStatus.Text = 'Accepted';
                        app.RxError.Text = 'No';
                        if (isequal(err, zeros(1,length(rx_msg))))
                            app.RxErrLoc.Text = 'N/A (Original message received)';
                        else
                            rxErrBits = strrep(num2str(err), ' ', '');
                            app.RxErrLoc.Text = rxErrBits;
                        end
                        msgBits = strrep(num2str(rx_msg(1:len2)), ' ', '');
                        app.RxDataword.Text = msgBits;
                    % If remainder is not zero: reject the frame
                    else
                        app.RxStatus.Text = 'Rejected';
                        app.RxError.Text = 'Yes';
                        rxErrBits = strrep(num2str(err), ' ', '');
                        app.RxErrLoc.Text = rxErrBits;
                        app.RxDataword.Text = 'N/A';
                    end
                else
                    app.SeqLenError_2.Text = 'Sequence length mismatch!';
                    app.RxMsg.Value = '';
                    app.RxErrLoc.Text = '';
                    app.RxRem.Text = '';
                    app.RxStatus.Text = '';
                    app.RxDataword.Text = '';            
                    app.RxError.Text = '';                    
                end
            end
        end

        % Button pushed function: AlterButton
        function AlterButtonPushed(app, event)
            tx_msg = num2str(app.TxMsg.Text) - '0';
            
            gen = num2str(app.Gen.Value) - '0';

            % Get the generator sequence length: (N+1) bits
            len = length(gen);
        
            % Get the generator sequence length: (K+N) bits
            error_msg = num2str(app.ErrorSeq.Value) - '0';
            error_len = length(error_msg);
            
            % Get the original message length: K bits
            len2 = length(tx_msg) - len+1;
            
            if (~isempty(app.ErrorSeq.Value))
                if (isequal(error_len, length(tx_msg)))
                    app.SeqLenError.Text = '';
                    new_msg = xor(tx_msg, error_msg);   
                    newSeq = strrep(num2str(new_msg), ' ', '');
                    app.UpdatedSeq.Text = newSeq;
                    %-------------------------------------------------------------
                    % Long division process: At the end of calculation, only the 
                    % remainder (N bits) is recorded 
                    %-------------------------------------------------------------
                    bit_cnt = len;      % next-bit count tracker
                    for i = 1:len2
                        bit_cnt = bit_cnt + 1;
                
                        % In the very first calculation, 'currVal' is the leftmost (N+1)-bit 
                        % MSB of the dividend, otherwise, it is based on the current
                        % 'nextVal' value
                        if (i == 1)
                            currVal = new_msg(1:len);
                        else
                            currVal = nextVal;
                        end
                        
                        % The value to be XOR'ed with 'currVal' is based on MSB of 'currVal' 
                        % MSB = 1: XOR with divisor, MSB = 0: XOR with 0's
                        if (currVal(1) == 1)
                            temp = xor(currVal, gen);   
                        else
                            temp = xor(currVal, zeros(1, len));
                        end
                        
                        % Lastly, if the calculation is not yet the last one, take the
                        % N 'temp' LSB bits and concatenate it with the next bit from the
                        % original message, otherwise, the resultant is the remainder
                        if (i < len2)
                            nextVal = [temp(2:end), new_msg(bit_cnt)];
                        else
                            remainder = temp(2:end);
                        end
                    end
                    
                    altRemBits = strrep(num2str(remainder), ' ', '');
                    app.AltRem.Text = altRemBits;
        
                    % If remainder is zero: accept the frame and show
                    % errors (if present)
                    if (isequal(remainder, zeros(1, len-1)))
                        app.AltStatus.Text = 'Accepted';
                        app.AltError.Text = 'No';
                        msgBits = strrep(num2str(new_msg(1:len2)), ' ', '');
                        app.AltDataword.Text = msgBits;
                    % If remainder is not zero: reject the frame
                    else
                        app.AltStatus.Text = 'Rejected';
                        app.AltError.Text = 'Yes';
                        app.AltDataword.Text = 'N/A';
                    end
                else
                    app.SeqLenError.Text = 'Sequence length mismatch!';
                    app.ErrorSeq.Value = '';
                    app.UpdatedSeq.Text = '';
                    app.AltRem.Text = '';
                    app.AltStatus.Text = '';
                    app.AltDataword.Text = '';            
                    app.AltError.Text = '';
                end
            end
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            app.delete;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % Disable Receive and Alter buttons and textboxes and enable
            % Generator buttons and textboxes
            app.RxMsg.Editable = false;
            app.RecButton.Enable = false;
            app.ErrorSeq.Editable = false;
            app.AlterButton.Enable = false;
            app.Msg.Editable = true;
            app.Gen.Editable = true;
            app.GenerateButton.Enable = true;   
            app.ResetButton.Enable = false;            
            app.SeqLenError_2.Text = '';
            
            % Clear all values
            app.Msg.Value = '';
            app.Gen.Value = '';
            app.GenFCS.Text = '';
            app.TxMsg.Text = '';
            
            % Clear receiver values
            app.RxMsg.Value = '';
            app.RxRem.Text = '';
            app.RxStatus.Text = '';
            app.RxDataword.Text = '';
            app.RxError.Text = '';
            app.RxErrLoc.Text = '';
            
            % Clear alter values            
            app.ErrorSeq.Value = '';
            app.UpdatedSeq.Text = '';
            app.AltRem.Text = '';
            app.AltStatus.Text = '';
            app.AltDataword.Text = '';            
            app.AltError.Text = '';
            app.SeqLenError.Text = '';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FCSErrorCheckingMechanismUIFigure = uifigure('Visible', 'off');
            app.FCSErrorCheckingMechanismUIFigure.Name = 'FCS Error Checking Mechanism';
            app.GeneratorPanel = uipanel(app.FCSErrorCheckingMechanismUIFigure);
            app.GeneratorPanel.Title = 'Generator';
            app.TransmittedsequenceLabel = uilabel(app.GeneratorPanel);
            app.TransmittedsequenceLabel.Text = 'Transmitted sequence:';
            app.FCSbits = uilabel(app.GeneratorPanel);
            app.FCSbits.Text = 'FCS bits:';
            app.GenFCS = uilabel(app.GeneratorPanel);
            app.GenFCS.Text = '';
            app.MessageLabel = uilabel(app.GeneratorPanel);
            app.MessageLabel.Text = 'Message:';
            app.Msg = uieditfield(app.GeneratorPanel, 'text');
            app.GeneratorsequenceLabel = uilabel(app.GeneratorPanel);
            app.GeneratorsequenceLabel.Text = 'Generator sequence:';
            app.Gen = uieditfield(app.GeneratorPanel, 'text');
            app.TxMsg = uilabel(app.GeneratorPanel);
            app.TxMsg.Text = '';
            app.GenerateButton = uibutton(app.GeneratorPanel, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.ReceiverPanel = uipanel(app.FCSErrorCheckingMechanismUIFigure);
            app.ReceiverPanel.Title = 'Receiver';
            app.ErrorlocationsLabel = uilabel(app.ReceiverPanel);
            app.ErrorlocationsLabel.Text = 'Error locations:';
            app.FCSbits_2 = uilabel(app.ReceiverPanel);
            app.FCSbits_2.Text = 'Errors detected?';
            app.RxError = uilabel(app.ReceiverPanel);
            app.RxError.Text = '';
            app.RxErrLoc = uilabel(app.ReceiverPanel);
            app.RxErrLoc.Text = '';
            app.FCSbits_3 = uilabel(app.ReceiverPanel);
            app.FCSbits_3.Text = 'Remainder:';
            app.RxRem = uilabel(app.ReceiverPanel);
            app.RxRem.Text = '';
            app.FCSbits_4 = uilabel(app.ReceiverPanel);
            app.FCSbits_4.Text = 'Frame status:';
            app.FCSbits_5 = uilabel(app.ReceiverPanel);
            app.FCSbits_5.Text = 'Message:';
            app.RxStatus = uilabel(app.ReceiverPanel);
            app.RxStatus.Text = '';
            app.RxDataword = uilabel(app.ReceiverPanel);
            app.RxDataword.Text = '';
            app.ReceivedsequenceLabel = uilabel(app.ReceiverPanel);
            app.ReceivedsequenceLabel.Text = 'Received sequence:';
            app.RxMsg = uieditfield(app.ReceiverPanel, 'text');
            app.RxMsg.Editable = 'off';
            app.RecButton = uibutton(app.ReceiverPanel, 'push');
            app.RecButton.ButtonPushedFcn = createCallbackFcn(app, @RecButtonPushed, true);
            app.RecButton.Enable = 'off';
            app.RecButton.Text = 'Check';
            app.SeqLenError_2 = uilabel(app.ReceiverPanel);
            app.SeqLenError_2.Text = '';
            app.AlterPanel = uipanel(app.FCSErrorCheckingMechanismUIFigure);
            app.AlterPanel.Title = 'Alter';
            app.FCSbits_6 = uilabel(app.AlterPanel);
            app.FCSbits_6.Text = 'Errors detected?';
            app.AltError = uilabel(app.AlterPanel);
            app.AltError.Text = '';
            app.FCSbits_7 = uilabel(app.AlterPanel);
            app.FCSbits_7.Text = 'Remainder:';
            app.AltRem = uilabel(app.AlterPanel);
            app.AltRem.Text = '';
            app.FCSbits_8 = uilabel(app.AlterPanel);
            app.FCSbits_8.Text = 'Frame status:';
            app.FCSbits_9 = uilabel(app.AlterPanel);
            app.FCSbits_9.Text = 'Message:';
            app.AltStatus = uilabel(app.AlterPanel);
            app.AltStatus.Text = '';
            app.AltDataword = uilabel(app.AlterPanel);
            app.AltDataword.Text = '';
            app.SeqLenError = uilabel(app.AlterPanel);
            app.SeqLenError.Text = '';
            app.FCSbits_11 = uilabel(app.AlterPanel);
            app.FCSbits_11.Text = 'Received sequence:';
            app.UpdatedSeq = uilabel(app.AlterPanel);
            app.UpdatedSeq.Text = '';
            app.ErrorsequenceLabel = uilabel(app.AlterPanel);
            app.ErrorsequenceLabel.Text = 'Error sequence:';
            app.ErrorSeq = uieditfield(app.AlterPanel, 'text');
            app.ErrorSeq.Editable = 'off';
            app.AlterButton = uibutton(app.AlterPanel, 'push');
            app.AlterButton.ButtonPushedFcn = createCallbackFcn(app, @AlterButtonPushed, true);
            app.AlterButton.Enable = 'off';
            app.AlterButton.Text = 'Check';
            app.ResetButton = uibutton(app.FCSErrorCheckingMechanismUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Enable = 'off';
            app.ResetButton.Text = 'Reset';
            app.ExitButton = uibutton(app.FCSErrorCheckingMechanismUIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            % Show the figure after all components are created
            app.FCSErrorCheckingMechanismUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FCS_Project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FCSErrorCheckingMechanismUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FCSErrorCheckingMechanismUIFigure)
        end
    end
end